classdef F1_PitWall_Dashboard < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        Lamp                       matlab.ui.control.Lamp
        LancerlanalysepisteButton  matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LancerlanalysepisteButton
        function LancerlanalysepisteButtonPushed(comp, event)
            try
                % 1. Indication visuelle : Orange (Calcul en cours)
                comp.Lamp.Color = [1 0.65 0];
                drawnow;

                vitesse      = evalin('base', 'vitesse');
                throttle     = evalin('base', 'throttle');
                time_seconds = evalin('base', 'time_seconds');

                % 2. Forcer tous les vecteurs en colonnes pour éviter les erreurs de dimension
                vitesse      = vitesse(:);
                throttle     = throttle(:);
                time_seconds = time_seconds(:);

                % 3. Lancement de la simulation Simulink
                simOut          = sim('f1_vehicle_model');
                vitesse_sim_kmh = simOut.simout.Data * 3.6;
                temps_sim       = simOut.simout.Time;

                % 4. Alignement temporel & IA de détection
                vitesse_sim_interp   = interp1(temps_sim, vitesse_sim_kmh, time_seconds, 'linear', 'extrap');
                residu               = abs(vitesse - vitesse_sim_interp);
                masque_full_throttle = throttle > 80;                                  % sans '
                seuil  = mean(residu(time_seconds < 35 & masque_full_throttle)) + 2 * std(residu(time_seconds < 35 & masque_full_throttle));
                alerte = double(residu > seuil & masque_full_throttle & time_seconds >= 35);

                % -------------------------------------------------------------
                % 5. TRACÉ DANS LE PREMIER GRAPHIQUE (comp.UIAxes)
                % -------------------------------------------------------------
                % Nettoyage de l'axe sans détruire la configuration
                cla(comp.UIAxes); 

                % Tracé de la vitesse réelle (Bleu) et Simulink (Rouge pointillé)
                plot(comp.UIAxes, time_seconds, vitesse, 'b', 'LineWidth', 1.5, 'DisplayName', 'Télémétrie Réelle');
                hold(comp.UIAxes, 'on');
                plot(comp.UIAxes, temps_sim, vitesse_sim_kmh, 'r--', 'LineWidth', 1.2, 'DisplayName', 'Modèle Simulink');
                hold(comp.UIAxes, 'off');

                % Habillage du graphique 1
                title(comp.UIAxes, 'Télémétrie & Jumeau Numérique (km/h)');
                xlabel(comp.UIAxes, 'Temps (s)');
                ylabel(comp.UIAxes, 'Vitesse (km/h)');
                grid(comp.UIAxes, 'on');
                legend(comp.UIAxes, 'Location', 'southwest');

                % -------------------------------------------------------------
                % 6. TRACÉ DANS LE DEUXIÈME GRAPHIQUE (comp.UIAxes2)
                % -------------------------------------------------------------
                cla(comp.UIAxes2);

                % Tracé du signal binaire d'alerte rouge
                area(comp.UIAxes2, time_seconds, alerte, 'FaceColor', [0.85 0 0], 'EdgeColor', 'none');

                % Habillage du graphique 2
                title(comp.UIAxes2, 'Détection d''Anomalie Aéro (IA)');
                xlabel(comp.UIAxes2, 'Temps (s)');
                ylabel(comp.UIAxes2, 'État Alerte');
                ylim(comp.UIAxes2, [-0.1 1.1]);
                grid(comp.UIAxes2, 'on');

                % -------------------------------------------------------------
                % 7. MAJ DE LA LAMPE (Vert = R.A.S, Rouge = Anomalie Aéro)
                % -------------------------------------------------------------
                if any(alerte)
                    comp.Lamp.Color = [1 0 0];   % ROUGE : Panne détectée
                else
                    comp.Lamp.Color = [0 1 0];   % VERT : Tout est conforme
                end

            catch ME
                comp.Lamp.Color = [1 0 0];
                disp(getReport(ME, 'extended'));
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, 'Télémétrie & Jumeau Numérique (km/h)')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes2 = uiaxes(comp.GridLayout);
            title(comp.UIAxes2, 'Détection d''Anomalie Aéro (IA)')
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.LancerlanalysepisteButton = uibutton(comp.GridLayout, 'push');
            comp.LancerlanalysepisteButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LancerlanalysepisteButtonPushed, true);
            comp.LancerlanalysepisteButton.Text = 'Lancer l''analyse piste';
            comp.Lamp = uilamp(comp.GridLayout);
        end
    end
end